classdef RenamingExt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ResetButton             matlab.ui.control.Button
        NewExtentionexjpgLabel  matlab.ui.control.Label
        UpdatedLabel            matlab.ui.control.Label
        EditField               matlab.ui.control.EditField
        TextArea_2              matlab.ui.control.TextArea
        SelectFilesLabel        matlab.ui.control.Label
        ConvertButton           matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        SaveButton              matlab.ui.control.Button
        BrowseButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        file_names % Description
        old_location % Description
        new_str % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [app.file_names,app.old_location] = uigetfile('*','Select a file','MultiSelect','on');
            app.TextArea.WordWrap = "off";
            app.TextArea.Value = app.file_names;
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            value = app.EditField.Value;
            if class(app.file_names) == "char" % case 1: Only 1 file selected --> if only one file is selected, length(flie_names) = length of characters in the name
                str = app.file_names;
                app.new_str = replaceBetween(str,'.',str(end),value,'Boundaries','inclusive');
            else                          % case 2: Multiple files selected --> if multiple file names are selected, length(flie_names) = number of files in the cell array
                for i = 1:length(app.file_names)
                    str = app.file_names(1,i);
                    str = char(str);
                    % to replace substring after '.' with string 'value'
                    app.new_str{1,i} = replaceBetween(str,'.',str(end),value,'Boundaries','inclusive');
                end
            end
            
            app.TextArea_2.Value = app.new_str;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Foldername = uigetdir('\'); 
            folder = [Foldername,'/'];
            
            if class(app.file_names) == "char"
                % to copy file from a certain folder and copy it in new location
                % with desired name
                copyfile(strcat(app.old_location,app.file_names),strcat(folder,app.new_str))
            else
                for i = 1:length(app.file_names)
                    copyfile(strcat(app.old_location,app.file_names{1,i}),strcat(folder,app.new_str{1,i}))
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.file_names = '';
            app.old_location = '';
            app.new_str = '';
            app.TextArea.Value = '';
            app.TextArea_2.Value ='';
            app.EditField.Value = '';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Tooltip = {''};
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.SelectFilesLabel = uilabel(app.UIFigure);
            app.SelectFilesLabel.Text = 'Select Files';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.UpdatedLabel = uilabel(app.UIFigure);
            app.UpdatedLabel.Text = 'Updated';
            app.NewExtentionexjpgLabel = uilabel(app.UIFigure);
            app.NewExtentionexjpgLabel.Text = {'New Extention'; '(ex .jpg)'};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RenamingExt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end